classdef GUI_Klasifikasi_DaunHerbal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image5                     matlab.ui.control.Image
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        UITable_3                  matlab.ui.control.Table
        UITable_2                  matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
        HasilKlasifikasiEditField  matlab.ui.control.EditField
        HasilKlasifikasiLabel      matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        KlasifikasiButton          matlab.ui.control.Button
        EkstrakWarnaButton         matlab.ui.control.Button
        EkstrakBentukButton        matlab.ui.control.Button
        EkstrakTeksturButton       matlab.ui.control.Button
        ThresholdingButton         matlab.ui.control.Button
        GaussionBlurButton         matlab.ui.control.Button
        RGBtoGrayscaleButton       matlab.ui.control.Button
        InputCitraButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImgOriginal     % citra RGB asli
        ImgGray         % grayscale
        ImgBlur         % gaussian
        ImgBW           % binary (threshold)
        meanLBP
        stdLBP
        aspectRatio
        eccentricity
        circularity
        convexity
        Rmean
        Gmean
        Bmean
        net             % neural network loaded from model_jst.mat
        mu              % mean vector for normalization
        sigma           % std vector for normalization
        categories      % class names
        labelOrderNames
        Image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % === Tambahkan Background Gambar Full Layar ===
        try
            if isprop(app, 'bgAxes') && isvalid(app.bgAxes)
            delete(app.bgAxes);
        end

        % Bu    at uiaxes untuk background
        bgAxes = uiaxes(app.UIFigure);
        bgAxes.Units = 'normalized';  % biar fleksibel terhadap resize
        bgAxes.Position = [-0.02 -0.02 1.04 1.04]; % sedikit meluber kiri, kanan, atas, bawah
        bgAxes.XColor = 'none';
        bgAxes.YColor = 'none';
        bgAxes.Visible = 'off';

        % Tampilkan gambar
        I = imread('green-natural-background-with-wooden-surface-picjumbo-com.jpeg');
        imshow(I, 'Parent', bgAxes, 'Border', 'tight');
        axis(bgAxes, 'off');
        axis(bgAxes, 'image');

        % Taruh di paling belakang
        uistack(bgAxes, 'bottom');

        % Simpan referensi
        app.bgAxes = bgAxes;

        catch ME
            warning('⚠️ Gagal memuat background: %s', ME.message);
        end

        % === Muat Model JST ===
        modelPath = 'model_jst.mat';
        if isfile(modelPath)
            S = load(modelPath);
            app.net = S.net;
            app.mu = S.mu;
            app.sigma = S.sigma;
            if isfield(S, 'labelOrderNames')
                app.labelOrderNames = S.labelOrderNames;
            elseif isfield(S, 'categories')
                app.labelOrderNames = S.categories;
            else
                app.labelOrderNames = {'Daun Jambu Biji','Daun Kunyit','Daun Pepaya','Daun Sirih'};
            end
            disp('✅ Model JST berhasil dimuat ke GUI.');
            disp('Urutan label dalam model:');
            disp(app.labelOrderNames);
                else
                uialert(app.UIFigure, ...
                'File model_jst.mat tidak ditemukan di folder kerja!', ...
                'Model tidak ditemukan');
            end
        end

        % Button pushed function: InputCitraButton
        function InputCitraButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp'}, 'Pilih Citra Daun');
            if isequal(file,0)
                return;
            end
            app.ImgOriginal = imread(fullfile(path,file));
            % tampilkan di axes
            imshow(app.ImgOriginal, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Citra Input');
            % bersihkan field lain
            app.ImgGray = []; app.ImgBlur = []; app.ImgBW = [];
            app.meanLBP = []; app.stdLBP = [];
            app.aspectRatio = []; app.eccentricity = []; app.circularity = []; app.convexity = [];
            app.Rmean = []; app.Gmean = []; app.Bmean = [];
            app.UITable.Data = {}; % kosongkan tabel
            app.HasilKlasifikasiEditField.Value = '';
        end

        % Button pushed function: RGBtoGrayscaleButton
        function RGBtoGrayscaleButtonPushed(app, event)
            if isempty(app.ImgOriginal)
                uialert(app.UIFigure,'Silakan input citra terlebih dahulu.','Peringatan'); return;
            end
            if size(app.ImgOriginal,3) == 3
                app.ImgGray = rgb2gray(app.ImgOriginal);
            else
                app.ImgGray = app.ImgOriginal;
            end
            imshow(app.ImgGray, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Grayscale');
        end

        % Button pushed function: GaussionBlurButton
        function GaussionBlurButtonPushed(app, event)
            if isempty(app.ImgGray)
                uialert(app.UIFigure,'Lakukan konversi grayscale terlebih dahulu.','Peringatan'); return;
            end
            app.ImgBlur = imgaussfilt(app.ImgGray, 2); % sigma=2
            imshow(app.ImgBlur, 'Parent', app.UIAxes_3);
            title(app.UIAxes_3, 'Gaussian Blur');
        end

        % Button pushed function: ThresholdingButton
        function ThresholdingButtonPushed(app, event)
            % Pastikan citra blur sudah ada
        if isempty(app.ImgBlur)
            uialert(app.UIFigure,'Lakukan Gaussian Blur terlebih dahulu.','Peringatan');
            return;
        end

        % Ubah ke grayscale & perbaiki kontras
        gray_adj = imadjust(app.ImgBlur);

        % Threshold Otsu
        T = graythresh(gray_adj);
        bw = imbinarize(gray_adj, T);

        % Deteksi otomatis apakah perlu dibalik
        white_ratio = sum(bw(:)) / numel(bw);
        if white_ratio > 0.5
            bw = imcomplement(bw);
        end

        % Bersihkan noise & lubang
        bw = imfill(bw, 'holes');
        bw = bwareaopen(bw, 500);

        % Simpan & tampilkan hasil
        app.ImgBW = bw;
        imshow(app.ImgBW, 'Parent', app.UIAxes_4);
        title(app.UIAxes_4, 'Thresholding');
        end

        % Button pushed function: EkstrakTeksturButton
        function EkstrakTeksturButtonPushed(app, event)
        if isempty(app.ImgGray)
            uialert(app.UIFigure,'Lakukan konversi grayscale terlebih dahulu.','Peringatan'); return;
        end
        lbp = extractLBPFeatures(app.ImgGray);
        app.meanLBP = mean(lbp);
        app.stdLBP = std(lbp);
        % update UITable (partial) - you will populate full table later
        data = app.UITable.Data;
        if isempty(data), data = {}; end
           % ensure table shows rows for features; here we rebuild fully in EkstraksiFitur/after other features
           app.UITable.Data = {'Mean LBP', app.meanLBP; 'Std LBP', app.stdLBP};
        end

        % Button pushed function: EkstrakBentukButton
        function EkstrakBentukButtonPushed(app, event)
            if isempty(app.ImgBW)
                uialert(app.UIFigure,'Lakukan thresholding dulu!','Peringatan'); return;
            end
            stats = regionprops(app.ImgBW, 'Area','Perimeter','Eccentricity','ConvexArea','BoundingBox');
            if isempty(stats)
                uialert(app.UIFigure,'Objek tidak terdeteksi pada citra biner.','Peringatan'); return;
            end
            s = stats(1);
            app.aspectRatio = s.BoundingBox(3) / (s.BoundingBox(4) + eps);
            app.eccentricity = s.Eccentricity;
            app.circularity = (4*pi*s.Area) / ( (s.Perimeter^2) + eps );
            app.convexity = s.ConvexArea / (s.Area + eps); % or area/convexarea depending prefer
            % update UITable (merge entries)
            app.UITable_2.Data = {
            'Aspect Ratio', app.aspectRatio;
            'Eccentricity', app.eccentricity;
            'Circularity', app.circularity;
            'Convexity', app.convexity
            };
        end

        % Button pushed function: EkstrakWarnaButton
        function EkstrakWarnaButtonPushed(app, event)
            if isempty(app.ImgOriginal)
                uialert(app.UIFigure,'Masukkan citra terlebih dahulu!','Peringatan'); return;
            end
            I = app.ImgOriginal;
            if size(I,3) ~= 3
                I = cat(3,I,I,I);
            end
            app.Rmean = mean2(I(:,:,1));
            app.Gmean = mean2(I(:,:,2));
            app.Bmean = mean2(I(:,:,3));
            % append/update UITable
            existing = app.UITable_3.Data;
            % build full table if other features exist
            app.UITable_3.Data = {
            'Mean R', app.Rmean;
            'Mean G', app.Gmean;
            'Mean B', app.Bmean
            };
        end

        % Button pushed function: KlasifikasiButton
        function KlasifikasiButtonPushed(app, event)
            try
                % Pastikan citra sudah diinput
                if isempty(app.ImgOriginal)
                    uialert(app.UIFigure, 'Silakan input citra terlebih dahulu.', 'Peringatan');
                    return;
                end

                % Cek apakah model sudah dimuat
                if ~isprop(app, 'net') || isempty(app.net)
                    uialert(app.UIFigure, 'Model JST belum dimuat. Pastikan model_jst.mat ada.', 'Error');
                    return;
                end

                % Ekstraksi fitur citra input
                I = app.ImgOriginal;
                I = imresize(I, [256 256]);
                gray = rgb2gray(I);

                % --- Fitur Tekstur (LBP) ---
                lbp = extractLBPFeatures(gray);
                meanLBP = mean(lbp);
                stdLBP = std(lbp);

                % --- Fitur Bentuk ---
                bw = imbinarize(gray);
                bw = imfill(bw, 'holes');
                stats = regionprops(bw, 'Area', 'Perimeter', 'Eccentricity', 'BoundingBox', 'ConvexArea');
                if isempty(stats)
                    aspectRatio = 0; eccentricity = 0; circularity = 0; convexity = 0;
                else
                    area = stats(1).Area;
                    perimeter = stats(1).Perimeter;
                    boundingBox = stats(1).BoundingBox;
                    aspectRatio = boundingBox(3) / boundingBox(4);
                    eccentricity = stats(1).Eccentricity;
                    circularity = (4 * pi * area) / (perimeter^2 + eps);
                    convexity = area / (stats(1).ConvexArea + eps);
                end

                % --- Fitur Warna ---
                Rmean = mean(mean(I(:,:,1)));
                Gmean = mean(mean(I(:,:,2)));
                Bmean = mean(mean(I(:,:,3)));

                fitur = [meanLBP, stdLBP, aspectRatio, eccentricity, circularity, convexity, Rmean, Gmean, Bmean];

                % Normalisasi
                fiturNorm = (fitur - app.mu) ./ app.sigma;

                % Klasifikasi
                y = app.net(fiturNorm');
                [~, idx] = max(y);

                % Tentukan hasil label berdasarkan model yang digunakan
                if isprop(app, 'labelOrderNames') && ~isempty(app.labelOrderNames)
                    hasil = app.labelOrderNames{idx};
                else
                    daftarLabel = {'jambu_biji','kunyit','pepaya','sirih'};
                    hasil = daftarLabel{idx};
                end

                % Tampilkan hasil
                app.HasilKlasifikasiEditField.Value = hasil;
                disp(['Probabilitas output y = ', num2str(y')]);
                disp(['Kelas terdeteksi = ', hasil]);

            catch ME
                uialert(app.UIFigure, ...
                ['Terjadi kesalahan saat klasifikasi: ' ME.message], ...
                'Error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % clear images
            cla(app.UIAxes); cla(app.UIAxes_2); cla(app.UIAxes_3); cla(app.UIAxes_4);
            % clear vars
            app.ImgOriginal = []; app.ImgGray = []; app.ImgBlur = []; app.ImgBW = [];
            app.meanLBP = []; app.stdLBP = [];
            app.aspectRatio = []; app.eccentricity = []; app.circularity = []; app.convexity = [];
            app.Rmean = []; app.Gmean = []; app.Bmean = [];
            app.UITable.Data = {};
            app.UITable_2.Data = {};
            app.UITable_3.Data = {};
            app.HasilKlasifikasiEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Thresholding')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            colormap(app.UIAxes_4, 'gray')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Gaussion Blur')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Grayscale')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Citra Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.InputCitraButton = uibutton(app.UIFigure, 'push');
            app.InputCitraButton.ButtonPushedFcn = createCallbackFcn(app, @InputCitraButtonPushed, true);
            app.InputCitraButton.Text = 'Input Citra';
            app.RGBtoGrayscaleButton = uibutton(app.UIFigure, 'push');
            app.RGBtoGrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoGrayscaleButtonPushed, true);
            app.RGBtoGrayscaleButton.Text = 'RGB to Grayscale';
            app.GaussionBlurButton = uibutton(app.UIFigure, 'push');
            app.GaussionBlurButton.ButtonPushedFcn = createCallbackFcn(app, @GaussionBlurButtonPushed, true);
            app.GaussionBlurButton.Text = 'Gaussion Blur';
            app.ThresholdingButton = uibutton(app.UIFigure, 'push');
            app.ThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdingButtonPushed, true);
            app.ThresholdingButton.Text = 'Thresholding';
            app.EkstrakTeksturButton = uibutton(app.UIFigure, 'push');
            app.EkstrakTeksturButton.ButtonPushedFcn = createCallbackFcn(app, @EkstrakTeksturButtonPushed, true);
            app.EkstrakTeksturButton.Text = 'Ekstrak Tekstur';
            app.EkstrakBentukButton = uibutton(app.UIFigure, 'push');
            app.EkstrakBentukButton.ButtonPushedFcn = createCallbackFcn(app, @EkstrakBentukButtonPushed, true);
            app.EkstrakBentukButton.Text = 'Ekstrak Bentuk';
            app.EkstrakWarnaButton = uibutton(app.UIFigure, 'push');
            app.EkstrakWarnaButton.ButtonPushedFcn = createCallbackFcn(app, @EkstrakWarnaButtonPushed, true);
            app.EkstrakWarnaButton.Text = 'Ekstrak Warna';
            app.KlasifikasiButton = uibutton(app.UIFigure, 'push');
            app.KlasifikasiButton.ButtonPushedFcn = createCallbackFcn(app, @KlasifikasiButtonPushed, true);
            app.KlasifikasiButton.Text = 'Klasifikasi';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HasilKlasifikasiLabel = uilabel(app.UIFigure);
            app.HasilKlasifikasiLabel.Text = 'Hasil Klasifikasi  ';
            app.HasilKlasifikasiEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Ekstraksi Tekstur'; 'Hasil'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Ekstraksi Bentuk'; 'Hasil'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Ekstraksi Warna'; 'Hasil'};
            app.UITable_3.RowName = {};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo-umi.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'judul-klasifikasi.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo-fikom.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'gambar-data-diri.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Klasifikasi_DaunHerbal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end